classdef Control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Lamp                    matlab.ui.control.Lamp
        EnginePowerSlider       matlab.ui.control.Slider
        EnginePowerSliderLabel  matlab.ui.control.Label
        WheelKnob               matlab.ui.control.Knob
        WheelKnobLabel          matlab.ui.control.Label
        TCSSwitch               matlab.ui.control.ToggleSwitch
        TCSSwitchLabel          matlab.ui.control.Label
        Gauge                   matlab.ui.control.SemicircularGauge
        GaugeLabel              matlab.ui.control.Label
        ResetButton             matlab.ui.control.Button
        PauseButton             matlab.ui.control.Button
        StartButton             matlab.ui.control.Button
        ControlPanelLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        TelemetryTimer
        ModelName = 'FSEA_Vehicle'
    end
    
    methods (Access = private)
       
function updateTelemetry(app)

    if ~isvalid(app) || ~isvalid(app.UIFigure)
        return
    end

    try
        status = get_param(app.ModelName, 'SimulationStatus');

        if ~strcmp(status, 'running')
            app.Lamp.Color = [0.5 0.5 0.5];
            return
        end

        % Read the current logged simulation data.
        logs = evalin('base', 'yout');
        veh = logs.get('Veh');

        vx = veh.Values.InertFrm.Cg.Vel.Xdot;
        vy = veh.Values.InertFrm.Cg.Vel.Ydot;

        % Use the latest available velocity samples.
        speedMS = hypot(vx.Data(end), vy.Data(end));
        speedKmh = 3.6 * speedMS;

        app.Gauge.Value = min(max(speedKmh, ...
            app.Gauge.Limits(1)), app.Gauge.Limits(2));

        app.Lamp.Color = [0 0.8 0];

    catch
        % Telemetry may not be available until logging updates.
    end
end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
    % Start the Simulink model if it is stopped
    simStatus = get_param(app.ModelName, 'SimulationStatus');

    if strcmp(simStatus, 'stopped')
        set_param(app.ModelName, 'SimulationCommand', 'start');
    end

    % Start telemetry updates if the timer is not already running
    if isempty(app.TelemetryTimer) || ...
            ~isvalid(app.TelemetryTimer)

        app.TelemetryTimer = timer( ...
            'ExecutionMode', 'fixedSpacing', ...
            'Period', 0.1, ...
            'TimerFcn', @(~,~) updateTelemetry(app));
    end

    if strcmp(app.TelemetryTimer.Running, 'off')
        start(app.TelemetryTimer);
    end

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            % Stop the telemetry timer
            if ~isempty(app.TelemetryTimer) && ...
                    isvalid(app.TelemetryTimer)

                stop(app.TelemetryTimer);
            end

            % Stop the simulation if it is running
            simStatus = get_param(app.ModelName, 'SimulationStatus');

            if ~strcmp(simStatus, 'stopped')
                set_param(app.ModelName, 'SimulationCommand', 'stop');
            end

            % Clear the telemetry display
            app.Gauge.Value = 0;
            app.Lamp.Color = [0.6 0.6 0.6];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ControlPanelLabel = uilabel(app.UIFigure);
            app.ControlPanelLabel.Text = 'Control Panel';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start ';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.Text = 'Pause';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'semicircular');
            app.TCSSwitchLabel = uilabel(app.UIFigure);
            app.TCSSwitchLabel.Text = 'TCS Switch';
            app.TCSSwitch = uiswitch(app.UIFigure, 'toggle');
            app.WheelKnobLabel = uilabel(app.UIFigure);
            app.WheelKnobLabel.Text = 'Wheel';
            app.WheelKnob = uiknob(app.UIFigure, 'continuous');
            app.WheelKnob.Limits = [-100 100];
            app.EnginePowerSliderLabel = uilabel(app.UIFigure);
            app.EnginePowerSliderLabel.Text = 'Engine Power';
            app.EnginePowerSlider = uislider(app.UIFigure);
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end